classdef project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        NghiemTab_2                     matlab.ui.container.Tab
        UIAxes3                         matlab.ui.control.UIAxes
        ChnphngphptmnghimButtonGroup    matlab.ui.container.ButtonGroup
        chiadoi                         matlab.ui.control.RadioButton
        lap                             matlab.ui.control.RadioButton
        tieptuyen                       matlab.ui.control.RadioButton
        TnhvvButton                     matlab.ui.control.Button
        LuKhongtnhphiccphpabLabel       matlab.ui.control.Label
        Khongtnhchc1nghimnLabel         matlab.ui.control.Label
        khoang                          matlab.ui.control.EditField
        KtquEditFieldLabel              matlab.ui.control.Label
        ketqua                          matlab.ui.control.EditField
        nhappt                          matlab.ui.control.EditField
        NhpvohmhmlpLabel                matlab.ui.control.Label
        SlnlpEditFieldLabel             matlab.ui.control.Label
        solanlap                        matlab.ui.control.NumericEditField
        SaisEditFieldLabel              matlab.ui.control.Label
        saiso                           matlab.ui.control.NumericEditField
        NoisuyTab                       matlab.ui.container.Tab
        Chonphuongphapnoisuy            matlab.ui.container.ButtonGroup
        NewtonButton                    matlab.ui.control.ToggleButton
        LagrangeButton                  matlab.ui.control.ToggleButton
        TnhtongitrvbiuthcButton         matlab.ui.control.Button
        GitrcnnisuyLabel                matlab.ui.control.Label
        GiaTri                          matlab.ui.control.EditField
        NhpgitrxEditFieldLabel          matlab.ui.control.Label
        x                               matlab.ui.control.NumericEditField
        NhpmngxEditFieldLabel           matlab.ui.control.Label
        xa                              matlab.ui.control.EditField
        NhpmngyEditFieldLabel           matlab.ui.control.Label
        ya                              matlab.ui.control.EditField
        BieuThucNoiSuy                  matlab.ui.control.Label
        HoiquyTab                       matlab.ui.container.Tab
        hoiquyUIAxes                    matlab.ui.control.UIAxes
        hoiQuyMethodButtonGroup         matlab.ui.container.ButtonGroup
        linearOptionButton              matlab.ui.control.RadioButton
        exponentialOptionButton         matlab.ui.control.RadioButton
        hoiQuyDrawerButton              matlab.ui.control.Button
        phuongTrinhHoiQuyTitle          matlab.ui.control.Label
        phuongTrinhHoiQuyLabel          matlab.ui.control.Label
        hoiQuyPredictionButton          matlab.ui.control.Button
        hoiQuyPredictionResultTitle     matlab.ui.control.Label
        hoiQuyErrorMessage              matlab.ui.control.Label
        hoiQuyPredictionResultLabel     matlab.ui.control.Label
        NhpmngXLabel                    matlab.ui.control.Label
        hoiQuyDuLieuXField              matlab.ui.control.EditField
        NhpmngYLabel                    matlab.ui.control.Label
        hoiQuyDuLieuYField              matlab.ui.control.EditField
        NhpgitrcndonLabel               matlab.ui.control.Label
        hoiQuyPredictorEditField        matlab.ui.control.NumericEditField
        DaohamTab                       matlab.ui.container.Tab
        bonustext_2                     matlab.ui.control.Label
        NhphmsEditFieldLabel_2          matlab.ui.control.Label
        Hamso_DaoHam                    matlab.ui.control.EditField
        NhpbchLabel                     matlab.ui.control.Label
        Buoch_DaoHam                    matlab.ui.control.EditField
        ChnphngphpButtonGroup           matlab.ui.container.ButtonGroup
        Tien_Button_DaoHam              matlab.ui.control.RadioButton
        Lui_Button_DaoHam               matlab.ui.control.RadioButton
        TrungTam_Button_DaoHam          matlab.ui.control.RadioButton
        KtquLabel                       matlab.ui.control.Label
        Ketqua_Label_DaoHam             matlab.ui.control.Label
        Tinh_Button_DaoHam              matlab.ui.control.Button
        NhpxEditFieldLabel              matlab.ui.control.Label
        XData_DaoHam                    matlab.ui.control.EditField
        NhpyEditFieldLabel              matlab.ui.control.Label
        YData_DaoHam                    matlab.ui.control.EditField
        NhpgitrcntnhohmEditFieldLabel   matlab.ui.control.Label
        Giatricantinh_EditField_DaoHam  matlab.ui.control.EditField
        Chongiatrisaiso_DaoHam_ButtonGroup  matlab.ui.container.ButtonGroup
        Oh_DaoHam_Button                matlab.ui.control.RadioButton
        Oh2_DaoHam_Button               matlab.ui.control.RadioButton
        Choncahtinh_DaoHam_ButtonGroup  matlab.ui.container.ButtonGroup
        MangXY_DaoHam_Button            matlab.ui.control.RadioButton
        Hamso_DaoHam_Button             matlab.ui.control.RadioButton
        TichPhanTab                     matlab.ui.container.Tab
        NhphmsEditFieldLabel            matlab.ui.control.Label
        Hamso                           matlab.ui.control.EditField
        NhpNLabel                       matlab.ui.control.Label
        dataN                           matlab.ui.control.NumericEditField
        CntrnbEditFieldLabel            matlab.ui.control.Label
        CanB                            matlab.ui.control.NumericEditField
        CndiaEditFieldLabel             matlab.ui.control.Label
        CanA                            matlab.ui.control.NumericEditField
        Methods                         matlab.ui.container.ButtonGroup
        HinhThang                       matlab.ui.control.RadioButton
        Simpson13                       matlab.ui.control.RadioButton
        Simpson38                       matlab.ui.control.RadioButton
        CalcIntegral                    matlab.ui.control.Button
        ChonInput                       matlab.ui.container.ButtonGroup
        VectorX_VectorY                 matlab.ui.control.RadioButton
        NhapHamSo                       matlab.ui.control.RadioButton
        KetQua                          matlab.ui.control.Label
        KetQuaLabel                     matlab.ui.control.Label
        NhpdliuXEditFieldLabel          matlab.ui.control.Label
        Xdata                           matlab.ui.control.EditField
        NhpdliuYEditFieldLabel          matlab.ui.control.Label
        Ydata                           matlab.ui.control.EditField
        IntroTab                        matlab.ui.container.Tab
        Tree                            matlab.ui.container.Tree
        THANHVIEN                       matlab.ui.container.TreeNode
        DinhDungNode                    matlab.ui.container.TreeNode
        VanHyNode                       matlab.ui.container.TreeNode
        QuangDungNode                   matlab.ui.container.TreeNode
        NguynHuyDuNode                  matlab.ui.container.TreeNode
        BaoQuocNode                     matlab.ui.container.TreeNode
        MinhQuangNode                   matlab.ui.container.TreeNode
        GIITHIUNHMLabel                 matlab.ui.control.Label
        MSSVLabel                       matlab.ui.control.Label
        MSSV                            matlab.ui.control.EditField
        NHIMVEditFieldLabel             matlab.ui.control.Label
        NHIEMVU                         matlab.ui.control.EditField
        NGYSINHEditFieldLabel           matlab.ui.control.Label
        NGAYSINH                        matlab.ui.control.EditField
        CHCVEditFieldLabel              matlab.ui.control.Label
        CHUCVU                          matlab.ui.control.EditField
        LPEditFieldLabel                matlab.ui.control.Label
        LOP                             matlab.ui.control.EditField
    end

    
    methods (Access = private)
        %---------------Tich phan tab ------------------------%
        
             % Hinh Thang function
             function y = hinhThang(app,fx,a,b,N)
                        h = (b -a)/N;
                        s1 = (fx(a) + fx(b));
                        s2 = 0;
                        for i = 1:N-1
                            s2 = s2 + fx(a + i*h);
                        end
                        y = h/2*(s1 + 2*s2);
    
             end
            
             % Simpson13 function
            function y = tichphanSimpson13(app,fx,a,b,N)
                        h = (b - a)/N;
                        s1 = (fx(a) + fx(b));
                        so = 0; %sum of odd
                        se = 0; %sum of even
                        for i=1:N-1
                            if(rem(i,2)==1) 
                                so = so + fx(a + i*h);
                            else
                                se = se + fx(a + i*h);
                            end
                        end
                        
                        y = h/3*(s1 + 4*so + 2*se);
            end
                  
    
            % A function to erase '.' character in fx
            function results = Change_Fx(app, fx)
                   results = strrep(fx,'*','.*');
                   results = strrep(results,'^','.^');
                   results = strrep(results,'/','./');
                
            end
      
        %Lagrange Function
        function result = Lagrange(app,xa, ya, x)
            n = length(xa);
            sum = 0;
            for i = 1:n
                product = ya(i);
                for j = 1:n
                    if i ~= j
                        product = product*(x - xa(j))/(xa(i) - xa(j));
                    end
                end
                sum = sum + product;
            end
            result = sum;
        end
    

        %Return lagrange 
        function f = Dathuc_Lagrange(app,xa, ya)
            syms x;
            f(x) = simplify(Lagrange(app,xa, ya, x));
        end

 
        % Simpson38 function
        function y = tichphanSimpson38(app,fx,a,b,N)
             h = (b - a)/N;
             s1 = (fx(a) + fx(b));
             s2 = 0; 
             s3 = 0;
            
             for i=1:N-1
                if(rem(i, 3) == 0)
                    s3 = s3 + fx(a + i*h);
                else
                    s2 = s2 + fx(a + i*h);
                end
             end
             
             
            y = (3 * h / 8) * (s1 + 3 * s2 + 2 * s3);
           
         end
        %-------------------Tich phan tab---------------------------%
        
                %Noi suy tab
        %     Đa thức nội suy Lagrange
        function result = Lagrange_NoiSuy(app,xa, ya, x)
            n = length(xa);
            sum = 0;
            for i = 1:n
                product = ya(i);
                for j = 1:n
                    if j ~= i
                        product = product .*(x - xa(j)) ./ (xa(i) - xa(j));
                    end
                end
                sum = sum + product;
            end
            result = sum;
        end
    
        % Newton
        function result = NoiSuyNewton(app, xa, ya, x)
            n = length(xa);
            d = ya;
            for i = 1:n
                for j = 1:i-1
                    d(i)=(d(j) - d(i))./(xa(j) - xa(i));
                end
            end
            n = length(d);
            result = d(n);
            for i = n-1:-1:1
                result = result.*(x - xa(i)) + d(i);
            end
        end

        function f = Dathuc_NoiSuy(app,xa, ya)
            syms x;
            f(x) = simplify(Lagrange_NoiSuy(app,xa, ya, x));
        end
        function f = BieuThuc(app,xa, ya)
                    syms x;
                    f(x) = simplify(NoiSuyNewton(app,xa, ya, x));
        end
        %Noi suy tab
     
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
           
            if(selectedNodes==app.MinhQuangNode)
                app.MSSV.Value = '21200339';
                app.NGAYSINH.Value = '02/01/2003';
                app.NHIEMVU.Value = 'Thiết kế Tab Giới thiệu nhóm';
                app.LOP.Value = '21DTV2';
                app.CHUCVU.Value = 'Thành Viên';
            end

            if (selectedNodes==app.BaoQuocNode)
                app.MSSV.Value = '21200344';
                app.NGAYSINH.Value = '10/10/2003';
                app.NHIEMVU.Value = 'Thiết kế Tab Tích phân';
                app.LOP.Value = '21DTV2';
                app.CHUCVU.Value = 'Thành Viên';
            end

            if (selectedNodes==app.NguynHuyDuNode)
                app.MSSV.Value = '21200279';
                app.NGAYSINH.Value = '20/08/2003';
                app.NHIEMVU.Value = 'Thiết kế Tab Đạo hàm';
                app.LOP.Value = '21DTV2';
                app.CHUCVU.Value = 'Thư ký';
            end
            if (selectedNodes==app.QuangDungNode)
                app.MSSV.Value = '21200282';
                app.NGAYSINH.Value = '18/12/2003';
                app.NHIEMVU.Value = 'Thiết kế Tab Hồi quy';
                app.LOP.Value = '21DTV2';
                app.CHUCVU.Value = 'Thành Viên';
            end
            if (selectedNodes==app.VanHyNode)
                app.MSSV.Value = '21200296';
                app.NGAYSINH.Value = '03/11/2003';
                app.NHIEMVU.Value = 'Thiết kế Tab Nội suy';
                app.LOP.Value = '21DTV2';
                app.CHUCVU.Value = 'Thành Viên';
            end
            if (selectedNodes==app.DinhDungNode)
                app.MSSV.Value = '21200280';
                app.NGAYSINH.Value = '16/06/2003';
                app.NHIEMVU.Value = 'Thiết kế Tab Tìm nghiệm';
                app.LOP.Value = '21DTV2';
                app.CHUCVU.Value = 'Trưởng nhóm';
            end 
        end

        % Value changed function: MSSV
        function MSSVValueChanged(app, event)
            value = app.MSSV.Value;
            
        end

        % Value changed function: NGAYSINH
        function NGAYSINHValueChanged(app, event)
            value = app.NGAYSINH.Value;
            
        end

        % Value changed function: LOP
        function LOPValueChanged(app, event)
            value = app.LOP.Value;
            
        end

        % Value changed function: CHUCVU
        function CHUCVUValueChanged(app, event)
            value = app.CHUCVU.Value;
            
        end

        % Value changed function: NHIEMVU
        function NHIEMVUValueChanged(app, event)
            value = app.NHIEMVU.Value;
            
        end

        % Button pushed function: CalcIntegral
        function CalcIntegralButtonPushed(app, event)
                
                a = app.CanA.Value;
                b = app.CanB.Value;
                N = app.dataN.Value;   
                fx = app.Hamso.Value;
                fx_new = Change_Fx(app,fx);
                syms x;
                fx_hand_new = str2func(['@(x)', fx_new]);
                
                ArrayX = str2num(app.Xdata.Value);
                ArrayY = str2num(app.Ydata.Value);
                fxFromArray = Dathuc_Lagrange(app,ArrayX, ArrayY);
                fx_handle = matlabFunction(fxFromArray);
%                 fx_new1 = func2str(fx_handle);
%                 fx_hand_new1 = Change_Fx(fx_new1);
%                  
                
               if(app.HinhThang.Value == 1 && app.NhapHamSo.Value == 1)
             
                    HTRes = hinhThang(app,fx_hand_new, a, b, N);
                    app.KetQua.Text = sprintf('%.10f', HTRes);
                    
               elseif(app.Simpson13.Value == 1 && app.NhapHamSo.Value == 1)
                   
                     SS13Res = tichphanSimpson13(app,fx_hand_new, a, b, N);
                     if(rem(N, 2) == 1)
                         app.KetQua.Text = sprintf("N không hợp lệ");
                     else
                         app.KetQua.Text = sprintf('%.10f', SS13Res);
                     end
                    
               elseif(app.Simpson38.Value == 1 && app.NhapHamSo.Value == 1)
                   SS38Res = tichphanSimpson38(app,fx_hand_new, a, b, N);
                   if (rem(N,3) ~= 0)
                       app.KetQua.Text = sprintf("N không hợp lệ");
                   else
                       app.KetQua.Text = sprintf('%.10f', SS38Res);
                   end
                   
           
               end
               
               if(app.HinhThang.Value == 1 && app.VectorX_VectorY.Value == 1)
             
                    HTRes = hinhThang(app,fx_handle, a, b, N);
                    app.KetQua.Text = sprintf('%.10f', HTRes);
                    
               elseif(app.Simpson13.Value == 1 && app.VectorX_VectorY.Value == 1)
                   
                     SS13Res = tichphanSimpson13(app,fx_handle, a, b, N);
                     if(rem(N, 2) == 1)
                         app.KetQua.Text = sprintf("N không hợp lệ");
                     else
                          app.KetQua.Text = sprintf('%.10f', SS13Res);
                     end
                   
               elseif(app.Simpson38.Value == 1 && app.VectorX_VectorY.Value == 1)
                   SS38Res = tichphanSimpson38(app,fx_handle, a, b, N);
                    if (rem(N,3) ~= 0)
                       app.KetQua.Text = sprintf("N không hợp lệ");
                   else
                       app.KetQua.Text = sprintf('%.10f', SS38Res);
                   end
                  
           
               end
        end

        % Selection changed function: ChonInput
        function ChonInputSelectionChanged(app, event)
            selectedButton = app.ChonInput.SelectedObject;
            if(selectedButton == app.VectorX_VectorY)
                app.Hamso.Visible = "off";
                app.Ydata.Visible = "on";
                app.Xdata.Visible = "on";
            else
                app.Hamso.Visible = "on";
                app.Ydata.Visible = "off";
                app.Xdata.Visible = "off";
            end
        end

        % Button pushed function: Tinh_Button_DaoHam
        function Tinh_Button_DaoHamPushed(app, event)
            mangX = str2num(app.XData_DaoHam.Value);
            mangY = str2num(app.YData_DaoHam.Value);
            f = app.Hamso_DaoHam.Value;
            f = str2func(['@(x)', f]);
            h = str2num(app.Buoch_DaoHam.Value);
            giatricantinh = str2num(app.Giatricantinh_EditField_DaoHam.Value);

            if app.Tien_Button_DaoHam.Value == 1
                pp = "Tiến";
            end
            if app.Lui_Button_DaoHam.Value == 1
                pp = "Lùi";
            end
            if app.TrungTam_Button_DaoHam.Value == 1
                pp = "Trung tâm";
            end

            if app.Oh2_DaoHam_Button.Value == 1
                saiso_daoham = "Oh2";
            end
            if app.Oh_DaoHam_Button.Value == 1
                saiso_daoham = "Oh";
            end

            if app.Hamso_DaoHam_Button.Value == 1
                df = xapxidaoham_hamso(f,h,pp,saiso_daoham,giatricantinh);
                app.Ketqua_Label_DaoHam.Text = formattedDisplayText(df,"NumericFormat","long");
            end

            if app.MangXY_DaoHam_Button.Value == 1
                df = tinhxapxidaoham(mangX,mangY,giatricantinh,pp);
                app.Ketqua_Label_DaoHam.Text = num2str(df);
            end
            
        end

        % Callback function
        function Chongiatrisaiso_DaoHam_ButtonGroupSelectionChanged(app, event)
            
        end

        % Selection changed function: Choncahtinh_DaoHam_ButtonGroup
        function Choncahtinh_DaoHam_ButtonGroupSelectionChanged(app, event)
            selectedButton = app.Choncahtinh_DaoHam_ButtonGroup.SelectedObject;
            selectedButton = app.MangXY_DaoHam_Button;
            if app.MangXY_DaoHam_Button.Value == 1
                app.Hamso_DaoHam.Visible = "off";
                app.Buoch_DaoHam.Visible = "off";
                app.XData_DaoHam.Visible = "on";
                app.YData_DaoHam.Visible = "on";
            else
                app.XData_DaoHam.Visible = "off";
                app.YData_DaoHam.Visible = "off";
                app.Hamso_DaoHam.Visible = "on";
                app.Buoch_DaoHam.Visible = "on";
            end
        end

        % Button pushed function: hoiQuyPredictionButton
        function hoiQuyPredictionButtonPushed(app, event)
              try
                x = str2num(app.hoiQuyDuLieuXField.Value);
                y = str2num(app.hoiQuyDuLieuYField.Value);
                
                
                option = app.hoiQuyMethodButtonGroup.SelectedObject.Value;
                fx = 0;
                if (option == app.linearOptionButton.Value)
                    fx = linearRegression(x, y);
                elseif (option == app.exponentialOptionButton.Value)
                    fx = exponentialRegression(x, y);           
                end
                
                x = app.hoiQuyPredictorEditField.Value;
                app.hoiQuyPredictionResultLabel.Text = num2str(fx(x));
                app.hoiQuyErrorMessage.Text = '';
            catch e
                app.hoiQuyErrorMessage.Text = e.message;
                disp(e);
            end
        end

        % Button pushed function: hoiQuyDrawerButton
        function hoiQuyDrawerButtonPushed(app, event)
            try
                x = str2num(app.hoiQuyDuLieuXField.Value);
                y = str2num(app.hoiQuyDuLieuYField.Value);
                
                pp = app.hoiQuyMethodButtonGroup.SelectedObject.Value;
                fx = 0;
                if (pp == app.linearOptionButton.Value)
                    fx = linearRegression(x, y);
                else
                    fx = exponentialRegression(x,y);
                end
                %  n = min(a) : 0.1 : max(a);
                % plot(app.hoiquyUIAxes, n, fx(n), 'b', 'LineWidth', 1);
                fplot(app.hoiquyUIAxes,fx);
                grid(app.hoiquyUIAxes, 'on');
                axis(app.hoiquyUIAxes);
                   s = func2str(fx);
                app.phuongTrinhHoiQuyLabel.Text = s(5:end);
                app.hoiQuyErrorMessage.Text = '';
                app.phuongTrinhHoiQuyLabel.Visible = true;
                app.phuongTrinhHoiQuyTitle.Visible = true;
                app.hoiQuyPredictionResultLabel.Visible = true;
                app.hoiQuyPredictionResultTitle.Visible = true;
            catch e
                app.hoiQuyErrorMessage.Text = e.message;

                app.phuongTrinhHoiQuyLabel.Visible = false;
                app.phuongTrinhHoiQuyTitle.Visible = false;
                app.hoiQuyPredictionResultLabel.Visible = false;
                app.hoiQuyPredictionResultTitle.Visible = false;
            end
                    
        end

        % Button pushed function: TnhvvButton
        function TnhvvButtonPushed2(app, event)
             syms x;
            f = str2func(['@(x)',app.nhappt.Value]);
            khoangtinh = app.khoang.Value; 
            khoangtinh = regexprep(khoangtinh, '[\[\]]', ''); % loại bỏ các ký tự [ và ]
            giatri = strsplit(khoangtinh, ';'); % tách chuỗi thành hai phần tử dựa trên dấu ;
            a = str2double(giatri{1}); % chuyển đổi chuỗi sang số
            b = str2double(giatri{2}); % chuyển đổi chuỗi sang số
            Saiso=app.saiso.Value;
            x1=linspace(a,b,1000);
            y=arrayfun(f,x1);%tính toán giá trị hàm f cho mỗi giá trị x1
            plot(app.UIAxes3,x1,y);
            if(app.chiadoi.Value==1)
                 fd = str2func(['@(x)' ,char(diff(f(x)))]);
                 if f(a) * f(b) > 0
                    app.ketqua.Value='f(a) và f(b) phải trái dấu kiểm tra khoảng nghiệm';
                    app.solanlap.Value=0;
                 return        
                 end
               
                 app.solanlap.Value=0;
                 while (b-a)/2 > Saiso
                        c = (a+b)/2;
                        if f(c)==0
                            break
                        elseif f(a)*f(c)<0
                            b=c;
                        else
                            a=c;
                        end
                        app.solanlap.Value=app.solanlap.Value+ 1;
                 end
                 app.ketqua.Value=num2str(sprintf('%.10f',(a+b)/2));
            end
            if(app.tieptuyen.Value==1)
                fd1 = str2func(['@(x)' ,char(diff(f(x)))]);
                fd2 = str2func(['@(x)' ,char(diff(diff(f(x))))]);
                app.solanlap.Value=0;         %khai báo biến đếm số lần lặp
                x0=a;
                nostop = 1;
                if f(a) * f(b) > 0
                        app.ketqua.Value='f(a) và f(b) phải trái dấu kiểm tra khoảng nghiệm';
                     return
                end
              
      
            if (nostop)
               while f(x0)*fd2(x0)<0    
                    x0 = (x0 + b) / 2;
                end 
                app.solanlap.Value = 0;
                while (1)
                    app.solanlap.Value = app.solanlap.Value + 1;
                    x1 = x0 - f(x0)/fd1(x0);
                if(abs(x1-x0)<app.saiso.Value)
                    break
                end
                x0=x1;
               end
                app.ketqua.Value = num2str(sprintf('%.10f',x1));
            end
         end
            if(app.lap.Value==1)
                lap_max=1000;
                app.solanlap.Value=0;     %khai báo biến đếm số lần lặp 
                i=2;            %thiết lập giá trị ban đầu của i=2
                z(1)=a;         %gán x(1)=a 
                z(2)=b;         %gán x(2)=b
                while(1)
                    if(app.solanlap.Value>lap_max)
                        return
                    end
                    app.solanlap.Value=app.solanlap.Value+1;    %biến lặp tăng 1 sau mỗi vòng lặp
                    z(i)=f(z(i-1));        %tính giá trị x(i) theo hàm lặp với giá trị x(i-1) trước đó
                    if abs(z(i)-z(i-1))<=app.saiso.Value       %hiệu tuyệt đối giua gia trị hiện tại và giá trị trước đó nếu nhỏ hơn sai số thì thoát lặp 
                    break;
                    end
                    i=i+1;    %tăng i để tính x(i) mới       
                end
                app.ketqua.Value=num2str(sprintf('%.10f',z(i)));  %gán nghiệm chính là x(i)
            end
        end

        % Button pushed function: TnhtongitrvbiuthcButton
        function TnhtongitrvbiuthcButtonPushed(app, event)
            xa = str2num(app.xa.Value);
            ya = str2num(app.ya.Value);                                                                  
            x = app.x.Value;                                                                           
              if (app.LagrangeButton.Value == 1)
                output = Lagrange_NoiSuy(app,xa, ya, x);  
                dathuc = Dathuc_NoiSuy(app,xa, ya);
                dathuc_handle = matlabFunction(dathuc);
                string = func2str(dathuc_handle);
                app.BieuThucNoiSuy.Text = string(5:end);
    
              else 
                output = NoiSuyNewton(app,xa, ya, x);
                bieuthuc = BieuThuc(app,xa, ya);
                bieuthuc_handle = matlabFunction(bieuthuc);
                string = func2str(bieuthuc_handle);
                app.BieuThucNoiSuy.Text = string(5:end);
               
             end

            result = ['  ' num2str(output)];
            app.GiaTri.Value = result;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NghiemTab_2 = uitab(app.TabGroup);
            app.NghiemTab_2.Title = 'Nghiệm';
            app.UIAxes3 = uiaxes(app.NghiemTab_2);
            title(app.UIAxes3, 'Đồ thị hàm số')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.ChnphngphptmnghimButtonGroup = uibuttongroup(app.NghiemTab_2);
            app.ChnphngphptmnghimButtonGroup.Title = 'Chọn phương pháp tìm nghiệm ';
            app.chiadoi = uiradiobutton(app.ChnphngphptmnghimButtonGroup);
            app.chiadoi.Text = 'Phương pháp chia đôi';
            app.chiadoi.Value = true;
            app.lap = uiradiobutton(app.ChnphngphptmnghimButtonGroup);
            app.lap.Text = 'Phương pháp lặp';
            app.tieptuyen = uiradiobutton(app.ChnphngphptmnghimButtonGroup);
            app.tieptuyen.Text = 'Phương pháp tiếp tuyến(Newton)';
            app.TnhvvButton = uibutton(app.NghiemTab_2, 'push');
            app.TnhvvButton.ButtonPushedFcn = createCallbackFcn(app, @TnhvvButtonPushed2, true);
            app.TnhvvButton.Text = 'Tính và vẽ';
            app.LuKhongtnhphiccphpabLabel = uilabel(app.NghiemTab_2);
            app.LuKhongtnhphiccphpabLabel.Text = 'Lưu ý: Khoảng tính phải có cú pháp [a;b]';
            app.Khongtnhchc1nghimnLabel = uilabel(app.NghiemTab_2);
            app.Khongtnhchc1nghimnLabel.Text = 'Khoảng tính(chỉ có 1 nghiệm đơn)';
            app.khoang = uieditfield(app.NghiemTab_2, 'text');
            app.khoang.Value = '[-1;2]';
            app.KtquEditFieldLabel = uilabel(app.NghiemTab_2);
            app.KtquEditFieldLabel.Text = 'Kết quả';
            app.ketqua = uieditfield(app.NghiemTab_2, 'text');
            app.nhappt = uieditfield(app.NghiemTab_2, 'text');
            app.nhappt.Value = 'x-sin(x)-0.25';
            app.NhpvohmhmlpLabel = uilabel(app.NghiemTab_2);
            app.NhpvohmhmlpLabel.Text = 'Nhập vào hàm/hàm lặp';
            app.SlnlpEditFieldLabel = uilabel(app.NghiemTab_2);
            app.SlnlpEditFieldLabel.Text = 'Số lần lặp';
            app.solanlap = uieditfield(app.NghiemTab_2, 'numeric');
            app.SaisEditFieldLabel = uilabel(app.NghiemTab_2);
            app.SaisEditFieldLabel.Text = 'Sai số';
            app.saiso = uieditfield(app.NghiemTab_2, 'numeric');
            app.saiso.Value = 0.005;
            app.NoisuyTab = uitab(app.TabGroup);
            app.NoisuyTab.Title = 'Nội Suy';
            app.Chonphuongphapnoisuy = uibuttongroup(app.NoisuyTab);
            app.Chonphuongphapnoisuy.Title = 'Chọn phương pháp nội suy';
            app.NewtonButton = uitogglebutton(app.Chonphuongphapnoisuy);
            app.NewtonButton.Text = 'Newton';
            app.NewtonButton.Value = true;
            app.LagrangeButton = uitogglebutton(app.Chonphuongphapnoisuy);
            app.LagrangeButton.Text = 'Lagrange';
            app.TnhtongitrvbiuthcButton = uibutton(app.NoisuyTab, 'push');
            app.TnhtongitrvbiuthcButton.ButtonPushedFcn = createCallbackFcn(app, @TnhtongitrvbiuthcButtonPushed, true);
            app.TnhtongitrvbiuthcButton.Text = 'Tính toán giá trị và biểu thức';
            app.GitrcnnisuyLabel = uilabel(app.NoisuyTab);
            app.GitrcnnisuyLabel.Text = 'Giá trị cần nội suy';
            app.GiaTri = uieditfield(app.NoisuyTab, 'text');
            app.NhpgitrxEditFieldLabel = uilabel(app.NoisuyTab);
            app.NhpgitrxEditFieldLabel.Text = 'Nhập giá trị x';
            app.x = uieditfield(app.NoisuyTab, 'numeric');
            app.x.Value = 0.14;
            app.NhpmngxEditFieldLabel = uilabel(app.NoisuyTab);
            app.NhpmngxEditFieldLabel.Text = 'Nhập mảng x';
            app.xa = uieditfield(app.NoisuyTab, 'text');
            app.xa.Value = '0.1 0.2 0.3 0.4';
            app.NhpmngyEditFieldLabel = uilabel(app.NoisuyTab);
            app.NhpmngyEditFieldLabel.Text = 'Nhập mảng y';
            app.ya = uieditfield(app.NoisuyTab, 'text');
            app.ya.Value = '0.09983 0.19867 0.29552 0.38942';
            app.BieuThucNoiSuy = uilabel(app.NoisuyTab);
            app.BieuThucNoiSuy.Text = 'Biểu thức nội suy';
            app.HoiquyTab = uitab(app.TabGroup);
            app.HoiquyTab.Title = 'Hồi Quy';
            app.hoiquyUIAxes = uiaxes(app.HoiquyTab);
            title(app.hoiquyUIAxes, 'Đồ thị hồi quy')
            xlabel(app.hoiquyUIAxes, 'X')
            ylabel(app.hoiquyUIAxes, 'Y')
            app.hoiQuyMethodButtonGroup = uibuttongroup(app.HoiquyTab);
            app.hoiQuyMethodButtonGroup.Title = 'Chọn phương pháp hồi quy';
            app.linearOptionButton = uiradiobutton(app.hoiQuyMethodButtonGroup);
            app.linearOptionButton.Text = 'Tuyến tính';
            app.linearOptionButton.Value = true;
            app.exponentialOptionButton = uiradiobutton(app.hoiQuyMethodButtonGroup);
            app.exponentialOptionButton.Text = 'Hàm mũ';
            app.hoiQuyDrawerButton = uibutton(app.HoiquyTab, 'push');
            app.hoiQuyDrawerButton.ButtonPushedFcn = createCallbackFcn(app, @hoiQuyDrawerButtonPushed, true);
            app.hoiQuyDrawerButton.Text = 'Tìm phương trình';
            app.phuongTrinhHoiQuyTitle = uilabel(app.HoiquyTab);
            app.phuongTrinhHoiQuyTitle.Text = 'Kết quả phương trình hồi quy';
            app.phuongTrinhHoiQuyLabel = uilabel(app.HoiquyTab);
            app.phuongTrinhHoiQuyLabel.Text = 'Kết quả phương trình hồi quy';
            app.hoiQuyPredictionButton = uibutton(app.HoiquyTab, 'push');
            app.hoiQuyPredictionButton.ButtonPushedFcn = createCallbackFcn(app, @hoiQuyPredictionButtonPushed, true);
            app.hoiQuyPredictionResultTitle = uilabel(app.HoiquyTab);
            app.hoiQuyPredictionResultTitle.Text = 'Kết quả dự đoán';
            app.hoiQuyErrorMessage = uilabel(app.HoiquyTab);
            app.hoiQuyPredictionResultLabel = uilabel(app.HoiquyTab);
            app.hoiQuyPredictionResultLabel.Text = '0';
            app.NhpmngXLabel = uilabel(app.HoiquyTab);
            app.NhpmngXLabel.Text = 'Nhập mảng X';
            app.hoiQuyDuLieuXField = uieditfield(app.HoiquyTab, 'text');
            app.hoiQuyDuLieuXField.Value = '[1 2 3 4 5 6 7 8 9]';
            app.NhpmngYLabel = uilabel(app.HoiquyTab);
            app.NhpmngYLabel.Text = 'Nhập mảng Y';
            app.hoiQuyDuLieuYField = uieditfield(app.HoiquyTab, 'text');
            app.hoiQuyDuLieuYField.Value = '[1 1.5 2 3 4 5 8 10 13]';
            app.NhpgitrcndonLabel = uilabel(app.HoiquyTab);
            app.NhpgitrcndonLabel.Text = 'Nhập giá trị cần dự đoán';
            app.hoiQuyPredictorEditField = uieditfield(app.HoiquyTab, 'numeric');
            app.hoiQuyPredictorEditField.Value = 2;
            app.DaohamTab = uitab(app.TabGroup);
            app.DaohamTab.Title = 'Đạo Hàm';
            app.bonustext_2 = uilabel(app.DaohamTab);
            app.bonustext_2.Text = 'Hoặc';
            app.NhphmsEditFieldLabel_2 = uilabel(app.DaohamTab);
            app.NhphmsEditFieldLabel_2.Text = 'Nhập hàm số';
            app.Hamso_DaoHam = uieditfield(app.DaohamTab, 'text');
            app.Hamso_DaoHam.Value = 'asin(x)';
            app.NhpbchLabel = uilabel(app.DaohamTab);
            app.NhpbchLabel.Text = 'Nhập bước h ';
            app.Buoch_DaoHam = uieditfield(app.DaohamTab, 'text');
            app.Buoch_DaoHam.Value = '1*10^-5';
            app.ChnphngphpButtonGroup = uibuttongroup(app.DaohamTab);
            app.ChnphngphpButtonGroup.Title = 'Chọn phương pháp ';
            app.Tien_Button_DaoHam = uiradiobutton(app.ChnphngphpButtonGroup);
            app.Tien_Button_DaoHam.Text = 'Xấp xỉ tiến ';
            app.Tien_Button_DaoHam.Value = true;
            app.Lui_Button_DaoHam = uiradiobutton(app.ChnphngphpButtonGroup);
            app.Lui_Button_DaoHam.Text = 'Xấp xỉ lùi ';
            app.TrungTam_Button_DaoHam = uiradiobutton(app.ChnphngphpButtonGroup);
            app.TrungTam_Button_DaoHam.Text = 'Xấp xỉ trung tâm ';
            app.KtquLabel = uilabel(app.DaohamTab);
            app.KtquLabel.Text = 'Kết quả ';
            app.Ketqua_Label_DaoHam = uilabel(app.DaohamTab);
            app.Ketqua_Label_DaoHam.Text = '0';
            app.Tinh_Button_DaoHam = uibutton(app.DaohamTab, 'push');
            app.Tinh_Button_DaoHam.ButtonPushedFcn = createCallbackFcn(app, @Tinh_Button_DaoHamPushed, true);
            app.Tinh_Button_DaoHam.Text = 'Tính ';
            app.NhpxEditFieldLabel = uilabel(app.DaohamTab);
            app.NhpxEditFieldLabel.Text = 'Nhập x ';
            app.XData_DaoHam = uieditfield(app.DaohamTab, 'text');
            app.XData_DaoHam.Value = '[0.1 0.3 0.5 0.7 0.9]';
            app.NhpyEditFieldLabel = uilabel(app.DaohamTab);
            app.NhpyEditFieldLabel.Text = 'Nhập y ';
            app.YData_DaoHam = uieditfield(app.DaohamTab, 'text');
            app.YData_DaoHam.Value = '[0.1002 0.3047 0.5236 0.7754 1.1198]';
            app.NhpgitrcntnhohmEditFieldLabel = uilabel(app.DaohamTab);
            app.NhpgitrcntnhohmEditFieldLabel.Text = 'Nhập giá trị cần tính Đạo hàm ';
            app.Giatricantinh_EditField_DaoHam = uieditfield(app.DaohamTab, 'text');
            app.Giatricantinh_EditField_DaoHam.Value = '0.7';
            app.Chongiatrisaiso_DaoHam_ButtonGroup = uibuttongroup(app.DaohamTab);
            app.Chongiatrisaiso_DaoHam_ButtonGroup.Title = 'Chọn giá trị sai số';
            app.Oh_DaoHam_Button = uiradiobutton(app.Chongiatrisaiso_DaoHam_ButtonGroup);
            app.Oh_DaoHam_Button.Text = 'O(h)';
            app.Oh_DaoHam_Button.Value = true;
            app.Oh2_DaoHam_Button = uiradiobutton(app.Chongiatrisaiso_DaoHam_ButtonGroup);
            app.Oh2_DaoHam_Button.Text = 'O(h^2)';
            app.Choncahtinh_DaoHam_ButtonGroup = uibuttongroup(app.DaohamTab);
            app.Choncahtinh_DaoHam_ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Choncahtinh_DaoHam_ButtonGroupSelectionChanged, true);
            app.Choncahtinh_DaoHam_ButtonGroup.Title = 'Chọn dữ liệu đầu vào';
            app.MangXY_DaoHam_Button = uiradiobutton(app.Choncahtinh_DaoHam_ButtonGroup);
            app.MangXY_DaoHam_Button.Text = 'Mảng X, Y';
            app.MangXY_DaoHam_Button.Value = true;
            app.Hamso_DaoHam_Button = uiradiobutton(app.Choncahtinh_DaoHam_ButtonGroup);
            app.Hamso_DaoHam_Button.Text = 'Hàm số';
            app.TichPhanTab = uitab(app.TabGroup);
            app.TichPhanTab.Title = 'Tích Phân';
            app.NhphmsEditFieldLabel = uilabel(app.TichPhanTab);
            app.NhphmsEditFieldLabel.Text = 'Nhập hàm số';
            app.Hamso = uieditfield(app.TichPhanTab, 'text');
            app.Hamso.Value = 'x^3*sin(x) + x*cos(x)';
            app.NhpNLabel = uilabel(app.TichPhanTab);
            app.NhpNLabel.Text = 'Nhập N';
            app.dataN = uieditfield(app.TichPhanTab, 'numeric');
            app.dataN.Value = 10;
            app.CntrnbEditFieldLabel = uilabel(app.TichPhanTab);
            app.CntrnbEditFieldLabel.Text = 'Cận trên (b)';
            app.CanB = uieditfield(app.TichPhanTab, 'numeric');
            app.CanB.Value = 2;
            app.CndiaEditFieldLabel = uilabel(app.TichPhanTab);
            app.CndiaEditFieldLabel.Text = 'Cận dưới (a)';
            app.CanA = uieditfield(app.TichPhanTab, 'numeric');
            app.Methods = uibuttongroup(app.TichPhanTab);
            app.Methods.Title = 'Chọn phương pháp tính tích phân';
            app.HinhThang = uiradiobutton(app.Methods);
            app.HinhThang.Text = 'Hình Thang';
            app.HinhThang.Value = true;
            app.Simpson13 = uiradiobutton(app.Methods);
            app.Simpson13.Text = 'Simpson 1/3';
            app.Simpson38 = uiradiobutton(app.Methods);
            app.Simpson38.Text = 'Simpson 3/8';
            app.CalcIntegral = uibutton(app.TichPhanTab, 'push');
            app.CalcIntegral.ButtonPushedFcn = createCallbackFcn(app, @CalcIntegralButtonPushed, true);
            app.CalcIntegral.Text = 'Tính ';
            app.ChonInput = uibuttongroup(app.TichPhanTab);
            app.ChonInput.SelectionChangedFcn = createCallbackFcn(app, @ChonInputSelectionChanged, true);
            app.ChonInput.Title = 'Chọn dữ liệu đầu vào';
            app.VectorX_VectorY = uiradiobutton(app.ChonInput);
            app.VectorX_VectorY.Text = 'Mảng x, Mảng y';
            app.VectorX_VectorY.Value = true;
            app.NhapHamSo = uiradiobutton(app.ChonInput);
            app.NhapHamSo.Text = 'Hàm số';
            app.KetQua = uilabel(app.TichPhanTab);
            app.KetQua.Text = '0';
            app.KetQuaLabel = uilabel(app.TichPhanTab);
            app.KetQuaLabel.Text = 'Kết Quả';
            app.NhpdliuXEditFieldLabel = uilabel(app.TichPhanTab);
            app.NhpdliuXEditFieldLabel.Text = 'Nhập dữ liệu X';
            app.Xdata = uieditfield(app.TichPhanTab, 'text');
            app.Xdata.Value = '[0.1, 0.2, 0.3, 0.4]';
            app.NhpdliuYEditFieldLabel = uilabel(app.TichPhanTab);
            app.NhpdliuYEditFieldLabel.Text = 'Nhập dữ liệu Y';
            app.Ydata = uieditfield(app.TichPhanTab, 'text');
            app.Ydata.Value = '[0.09983, 0.19867, 0.29552, 0.38942]';
            app.IntroTab = uitab(app.TabGroup);
            app.IntroTab.Title = 'Giới thiệu nhóm';
            app.Tree = uitree(app.IntroTab);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.THANHVIEN = uitreenode(app.Tree);
            app.THANHVIEN.Text = '   THÀNH VIÊN';
            app.DinhDungNode = uitreenode(app.THANHVIEN);
            app.DinhDungNode.Text = 'Lê Đình Dũng';
            app.VanHyNode = uitreenode(app.THANHVIEN);
            app.VanHyNode.Text = 'Đặng Văn Hy';
            app.QuangDungNode = uitreenode(app.THANHVIEN);
            app.QuangDungNode.Text = 'Phạm Quang Dũng';
            app.NguynHuyDuNode = uitreenode(app.THANHVIEN);
            app.NguynHuyDuNode.Text = 'Nguyễn Huy Du';
            app.BaoQuocNode = uitreenode(app.THANHVIEN);
            app.BaoQuocNode.Text = 'Phạm Bảo Quốc';
            app.MinhQuangNode = uitreenode(app.THANHVIEN);
            app.MinhQuangNode.Text = 'Đỗ Minh Quang';
            app.GIITHIUNHMLabel = uilabel(app.IntroTab);
            app.GIITHIUNHMLabel.Text = 'GIỚI THIỆU NHÓM';
            app.MSSVLabel = uilabel(app.IntroTab);
            app.MSSVLabel.Text = 'MSSV ';
            app.MSSV = uieditfield(app.IntroTab, 'text');
            app.MSSV.ValueChangedFcn = createCallbackFcn(app, @MSSVValueChanged, true);
            app.MSSV.Editable = 'off';
            app.NHIMVEditFieldLabel = uilabel(app.IntroTab);
            app.NHIMVEditFieldLabel.Text = 'NHIỆM VỤ';
            app.NHIEMVU = uieditfield(app.IntroTab, 'text');
            app.NHIEMVU.ValueChangedFcn = createCallbackFcn(app, @NHIEMVUValueChanged, true);
            app.NHIEMVU.Editable = 'off';
            app.NGYSINHEditFieldLabel = uilabel(app.IntroTab);
            app.NGYSINHEditFieldLabel.Text = 'NGÀY SINH';
            app.NGAYSINH = uieditfield(app.IntroTab, 'text');
            app.NGAYSINH.ValueChangedFcn = createCallbackFcn(app, @NGAYSINHValueChanged, true);
            app.NGAYSINH.Editable = 'off';
            app.CHCVEditFieldLabel = uilabel(app.IntroTab);
            app.CHCVEditFieldLabel.Text = 'CHỨC VỤ';
            app.CHUCVU = uieditfield(app.IntroTab, 'text');
            app.CHUCVU.ValueChangedFcn = createCallbackFcn(app, @CHUCVUValueChanged, true);
            app.CHUCVU.Editable = 'off';
            app.LPEditFieldLabel = uilabel(app.IntroTab);
            app.LPEditFieldLabel.Text = 'LỚP';
            app.LOP = uieditfield(app.IntroTab, 'text');
            app.LOP.ValueChangedFcn = createCallbackFcn(app, @LOPValueChanged, true);
            app.LOP.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end